classdef DatabaseAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        DatabaseSelectionTab         matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        SelectDatabaseDropDown       matlab.ui.control.DropDown
        SelectDatabaseDropDownLabel  matlab.ui.control.Label
        GeometryFound                matlab.ui.control.Label
        RefAx                        matlab.ui.control.UIAxes
        ProfilesTab                  matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        RTSRDlbl                     matlab.ui.control.Label
        Int2                         matlab.ui.control.Label
        Int1                         matlab.ui.control.Label
        GridLayout5                  matlab.ui.container.GridLayout
        AlphaLabel                   matlab.ui.control.Label
        RadialpositionLabel          matlab.ui.control.Label
        Y25Label                     matlab.ui.control.Label
        y25g                         matlab.ui.control.Label
        X25Label                     matlab.ui.control.Label
        x25g                         matlab.ui.control.Label
        cRLabel                      matlab.ui.control.Label
        cRg                          matlab.ui.control.Label
        RadialpositionGauge          matlab.ui.control.LinearGauge
        AlphaGauge                   matlab.ui.control.NinetyDegreeGauge
        GridLayout3                  matlab.ui.container.GridLayout
        ProfileNumberDropDown        matlab.ui.control.DropDown
        ProfileNumberDropDownLabel   matlab.ui.control.Label
        Target                       matlab.ui.control.UIAxes
        p2Ref                        matlab.ui.control.UIAxes
        RealMeasures                 matlab.ui.control.UIAxes
        Polyroot                     matlab.ui.control.UIAxes
        Intersections                matlab.ui.control.UIAxes
        RTSGI                        matlab.ui.control.UIAxes
        RTSRD                        matlab.ui.control.UIAxes
        DistributionsTab             matlab.ui.container.Tab
        GridLayout6                  matlab.ui.container.GridLayout
        ClearTwistDataButton         matlab.ui.control.Button
        ClearChorddataButton         matlab.ui.control.Button
        UploadTwistDataButton        matlab.ui.control.Button
        UploadChorddataButton        matlab.ui.control.Button
        y25ax                        matlab.ui.control.UIAxes
        x25ax                        matlab.ui.control.UIAxes
        ChordAx                      matlab.ui.control.UIAxes
        Twistax                      matlab.ui.control.UIAxes
        InterpolatedPolarTab         matlab.ui.container.Tab
        GridLayout7                  matlab.ui.container.GridLayout
        OOBLabel                     matlab.ui.control.Label
        HoldSwitch                   matlab.ui.control.Switch
        HoldSwitchLabel              matlab.ui.control.Label
        ReynoldsEditField            matlab.ui.control.NumericEditField
        ReynoldsEditFieldLabel       matlab.ui.control.Label
        MachEditField                matlab.ui.control.NumericEditField
        MachEditFieldLabel           matlab.ui.control.Label
        RadiusEditField              matlab.ui.control.NumericEditField
        RadiusEditFieldLabel         matlab.ui.control.Label
        ClearButton                  matlab.ui.control.Button
        PlotButton                   matlab.ui.control.Button
        PolarCD                      matlab.ui.control.UIAxes
        PolarCL                      matlab.ui.control.UIAxes
        ChordAx_3                    matlab.ui.control.UIAxes
        ChordAx_2                    matlab.ui.control.UIAxes
        UIAxes_8                     matlab.ui.control.UIAxes
        UIAxes_7                     matlab.ui.control.UIAxes
        UIAxes_4                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        SavedLegend string % String to save legend
    end
    
    methods (Access = public)
        
        
    end
    
    methods (Access = private)
        %% Checking boundaries when input for graph modification
    function value=checkfieldsOOB(~,MinA,MaxA,MinRe,MaxRe,MinMach,MaxMach,a,Re,Mach,r)
    OutOfBOunds=false;

    if not(a<=MaxA && a>=MinA)
        OutOfBOunds=true;
        fprintf(2,"Alpha out of boundaries of database\n")
    end
    
    if not(Re<=MaxRe && Re>=MinRe)
        OutOfBOunds=true;
        fprintf(2,"Reynolds out of boundaries of database\n")
    end

    if not(Mach<=MaxMach && Mach>=MinMach)
        OutOfBOunds=true;
        fprintf(2,"Mach out of boundaries of database\n")
    end

    if not(r<=1 && r>=0)
        OutOfBOunds=true;
        fprintf(2,"Mach out of boundaries of database\n")
    end

    value=OutOfBOunds;
    end


        %% Update everythin in page 3 when db changes
        function ChordTwistChange(app,Database)

            if isfield(Database,"TR") && isfield(Database,"PlotData")
                IsData=true;
            else
                IsData=false;
            end

                TwistAxs=app.Twistax;
                ChordAxs=app.ChordAx;
                x25Axs=app.x25ax;
                y25Axs=app.y25ax;

                cla(TwistAxs)
                cla(ChordAxs)

            if IsData
                plot(ChordAxs,[Database.PlotData(:).RadPos],[Database.PlotData(:).ChordR]);
                xlabel(ChordAxs,"r/R");
                ylabel(ChordAxs,"c/R");
                legend(ChordAxs,"Extracted");

                plot(TwistAxs,[Database.PlotData(:).RadPos],[Database.PlotData(:).alpha]);
                xlabel(TwistAxs,"r/R");
                ylabel(TwistAxs,"alpha°");
                legend(TwistAxs,"Extracted");

                plot(x25Axs,[Database.PlotData(:).RadPos],[Database.PlotData(:).X25]);
                xlabel(x25Axs,"r/R");
                ylabel(x25Axs,"");
                legend(x25Axs,"Extracted");

                plot(y25Axs,[Database.PlotData(:).RadPos],[Database.PlotData(:).Y25]);
                xlabel(y25Axs,"r/R");
                ylabel(y25Axs,"");
                legend(y25Axs,"Extracted");

            else
            end


        end

        %% Update everything in page 2 when a profile is changed
        function ProfileChanged(app,id,Database)
            
            if isfield(Database,"TR") && isfield(Database,"PlotData")
                IsData=true;
            else
                IsData=false;
            end

            %Pg2 axes
            AxRD=app.RealMeasures;
            AxInt=app.Intersections;
            AxRTSRD=app.RTSRD;
            AxRTSGI=app.RTSGI;
            AxTarget=app.Target;
            AxPolyroot=app.Polyroot;
            Ax3DRef=app.p2Ref;

            AxesHandleArray=[AxRD,AxInt,AxRTSRD,AxRTSGI,AxTarget,AxPolyroot];

            if IsData %Checking data existence

                for Ax=AxesHandleArray
                    cla(Ax)
                end
                cla(Ax3DRef)

                %Data from db
                RealMeaures=Database.PlotData(id).RealMeasures;
                Inters=Database.PlotData(id).Intersections;
                RTSRDs=Database.PlotData(id).rtsrd;
                RTSGIs=Database.PlotData(id).rtsgi;
                Targets=Database.PlotData(id).target;
                Polyroots=Database.PlotData(id).polyroot;
                X25=Database.PlotData(id).X25;
                Y25=Database.PlotData(id).Y25;
                TR=Database.TR;
                RadPos=Database.PlotData(id).RadPos;
                Alpha=Database.PlotData(id).alpha;

                %gauge data updates
                app.cRg.Text=num2str(Database.PlotData(id).ChordR);
                app.x25g.Text=num2str(Database.PlotData(id).X25);
                app.y25g.Text=num2str(Database.PlotData(id).Y25);
                app.RadialpositionGauge.Value=RadPos;
                app.AlphaGauge.Value=Alpha;
                app.RadialpositionGauge.Tooltip=num2str(RadPos);
                app.AlphaGauge.Tooltip=num2str(Alpha);

                %Plotting time

                for Ax=AxesHandleArray
                    axis(Ax,"equal")
                end


                hold(AxRD,"on");
                plot(AxRD,RealMeaures(:,1),RealMeaures(:,2),".");
                plot(AxRD,X25,Y25,"o");
                hold(AxRD,"off");
                
                if not(isempty(Inters))
                    hold(AxInt,"on");
                    plot(AxInt,Inters(:,1),Inters(:,2),".");      
                    axis(AxInt,"equal");
                    hold(AxInt,"off");

                    hold(AxRTSGI,"on");
                    plot(AxRTSGI,RTSGIs(:,1),RTSGIs(:,2),".");      
                    xlim(AxRTSGI,[-0.1 1.1])
                    ylim(AxRTSGI,[-0.2 0.2])
                    hold(AxRTSGI,"off");
                    app.Int1.Text="";
                    app.Int2.Text="";
                else
                    app.Int1.Text="Not avaliable";
                    app.Int2.Text="Not avaliable";
                end

                if not(isempty(RTSRDs))
                hold(AxRTSRD,"on");
                plot(AxRTSRD,RTSRDs(:,1),RTSRDs(:,2),".");      
                xlim(AxRTSRD,[-0.1 1.1])
                ylim(AxRTSRD,[-0.2 0.2])
                hold(AxRTSRD,"off");
                app.RTSRDlbl.Text="";
                else
                    app.RTSRDlbl.Text="Not needed";
                end

                hold(AxTarget,"on");
                plot(AxTarget,Targets(:,1),Targets(:,2));      
                xlim(AxTarget,[-0.1 1.1])
                ylim(AxTarget,[-0.2 0.2])
                hold(AxTarget,"off");

                hold(AxPolyroot,"on");
                plot(AxPolyroot,Polyroots(:,1),Polyroots(:,2));      
                xlim(AxPolyroot,[-0.1 1.1])
                ylim(AxPolyroot,[-0.2 0.2])
                hold(AxPolyroot,"off");

                %3D reference
                Output=StlGeometryAutomaticFix(TR);
                XX=Output.Points;
                xx=XX(:,1);
                yy=XX(:,2);
                zz=XX(:,3);
                xpmin=min(xx);
                xpmax=max(xx);
                ypmin=min(yy);
                ypmax=max(yy);
                [xp,yp] = meshgrid([xpmin xpmax],[ypmin ypmax]);
                plot3(Ax3DRef,-xx,zz,yy,"."); %Perfect rotation: -xx.zz.yy
                view(Ax3DRef,85,45)
                hold(Ax3DRef,"on")
                axis(Ax3DRef,"equal")
                plot3(Ax3DRef,0,0,0,"o","Color","red","MarkerSize",10)
                zmin=min(zz);
                zmax=max(zz);
                z=RadPos*zmax;
                zp = 0.*xp + 0.*xp + z;
                surf(Ax3DRef,-xp,zp,yp,"FaceColor","yellow");
                hold(Ax3DRef,"off")


            else %no data
                for Ax=AxesHandleArray
                    cla(Ax)
                    cla(Ax3DRef)
                end
            end


        end


        %Updating all data when a different db is selected
        function DbChanged(app,Name)
            
            Name=string(Name);
            load(Name,"Database")

            if isfield(Database,"TR") && isfield(Database,"PlotData")
                IsData=true;
            else
                IsData=false;
            end


            %Getting all axes
            %Pg 1
            Ax1=app.RefAx;


            %% Plotting data
            
            
            if IsData
                app.GeometryFound.Text="";
                
                TR=Database.TR;
                Output=StlGeometryAutomaticFix(TR);
                XX=Output.Points;
                xx=XX(:,1);
                yy=XX(:,2);
                zz=XX(:,3);
                
                cla(Ax1)
                plot3(Ax1,-xx,zz,yy,"."); %Perfect rotation: -xx.zz.yy
                hold(Ax1,"on")
                plot3(Ax1,0,0,0,"o","Color","red","MarkerSize",10)
                axis(Ax1,"equal")
                hold(Ax1,"off")

                %Updating profiles dropdown
                [~,ProfilesNumber]=size(Database.PlotData);
                Profiles=linspace(1,ProfilesNumber,ProfilesNumber);
                Profiles = cellstr(num2str(Profiles(:)));
                app.ProfileNumberDropDown.Items=Profiles;

                %Updating Profiles
                ProfileChanged(app,1,Database);
                %ChordTwistChange
                ChordTwistChange(app,Database)
                
            else
                Ax1=app.RefAx;
                cla(Ax1)
                app.GeometryFound.Text="Geometry data not found";
            end


        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            

            %Adding crucial directories to path
            addpath("Functions\","STL\")

            %setting databases in dropdown list
            DbList=dir("*.mat");
            DbList=struct2cell(DbList);
            app.SelectDatabaseDropDown.Items=DbList(1,:);
            
            %Printing smt on graphic 1 so it isnt empty
            DbChanged(app,DbList(1,1));

        end

        % Value changed function: SelectDatabaseDropDown
        function SelectDatabaseDropDownValueChanged(app, event)
            value = app.SelectDatabaseDropDown.Value;
            DbChanged(app,value)
        end

        % Value changed function: ProfileNumberDropDown
        function ProfileNumberDropDownValueChanged(app, event)
            id = app.ProfileNumberDropDown.Value;
            IdInt = double(id) - double('0');
            DbName=string(app.SelectDatabaseDropDown.Value);
            load(DbName,"Database");
            ProfileChanged(app,IdInt,Database)
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

            Clax=app.PolarCL;
            Cdax=app.PolarCD;

            if app.HoldSwitch.Value=="On"
                hold(Clax,"on")
                hold(Cdax,"on")
            else
                hold(Clax,"off")
                hold(Cdax,"off")
                app.SavedLegend=[];
            end
            

            resolution=10;

            Dbpath=app.SelectDatabaseDropDown.Value;
            load(Dbpath,"Database")

            MaxAlpha=max(Database.grid.a);
            MinAlpha=min(Database.grid.a);
            MaxRe=max(Database.grid.Re);
            MinRe=min(Database.grid.Re);
            MaxM=max(Database.grid.Mach);
            MinM=min(Database.grid.Mach);
            
            Re=app.ReynoldsEditField.Value;
            Mach=app.MachEditField.Value;
            r=app.RadiusEditField.Value;
            
            outofboundaries=checkfieldsOOB(app,MinAlpha,MaxAlpha,MinRe,MaxRe,MinM,MaxM,(MaxAlpha+MinAlpha)/2,Re,Mach,r);
            
            if not(outofboundaries)
            
                Cls=zeros(1,resolution);
                Cds=zeros(1,resolution);
                k=1;
                alphas=linspace(MinAlpha,MaxAlpha,resolution);

                for a=alphas
                    Values=GetPolar(r,a,Re,Mach,Database);
                    Cls(k)=Values.CL;
                    Cds(k)=Values.CD;
                    k=k+1;
                end

                legend_string=sprintf("R=%.2f Re=%.0f Mach=%.2f",r,Re,Mach);
                app.SavedLegend=[app.SavedLegend,legend_string];

                plot(Clax,alphas,Cls)
                legend(Clax,app.SavedLegend)

                plot(Cdax,alphas,Cds)
                legend(Cdax,app.SavedLegend)

                app.OOBLabel.Text="";
            else
                app.OOBLabel.Text=sprintf("Values out of boundaries. Allowed values:\nAlpha: %d to %d\nMach: %d to %d\nRe: %d to %d",MinAlpha,MaxAlpha,MinM,MaxM,MinRe,MaxRe);
            end


        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            Clax=app.PolarCL;
            Cdax=app.PolarCD;
            cla(Cdax);
            cla(Clax);
            app.SavedLegend=[];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, 'Title')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, 'Title')
            xlabel(app.UIAxes_8, 'X')
            ylabel(app.UIAxes_8, 'Y')
            zlabel(app.UIAxes_8, 'Z')
            app.ChordAx_2 = uiaxes(app.UIFigure);
            title(app.ChordAx_2, 'Chord')
            xlabel(app.ChordAx_2, 'X')
            ylabel(app.ChordAx_2, 'Y')
            zlabel(app.ChordAx_2, 'Z')
            app.ChordAx_3 = uiaxes(app.UIFigure);
            title(app.ChordAx_3, 'Chord')
            xlabel(app.ChordAx_3, 'X')
            ylabel(app.ChordAx_3, 'Y')
            zlabel(app.ChordAx_3, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DatabaseSelectionTab = uitab(app.TabGroup);
            app.DatabaseSelectionTab.Title = 'Database Selection';
            app.GridLayout2 = uigridlayout(app.DatabaseSelectionTab);
            app.RefAx = uiaxes(app.GridLayout2);
            title(app.RefAx, '3D Reference')
            xlabel(app.RefAx, 'X')
            ylabel(app.RefAx, 'Y')
            zlabel(app.RefAx, 'Z')
            app.GeometryFound = uilabel(app.GridLayout2);
            app.GeometryFound.Text = '';
            app.SelectDatabaseDropDownLabel = uilabel(app.GridLayout2);
            app.SelectDatabaseDropDownLabel.Text = 'Select Database';
            app.SelectDatabaseDropDown = uidropdown(app.GridLayout2);
            app.SelectDatabaseDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDatabaseDropDownValueChanged, true);
            app.ProfilesTab = uitab(app.TabGroup);
            app.ProfilesTab.Title = 'Profiles';
            app.GridLayout = uigridlayout(app.ProfilesTab);
            app.RTSRD = uiaxes(app.GridLayout);
            title(app.RTSRD, 'Rotated translated scaled real measures')
            xlabel(app.RTSRD, 'X')
            ylabel(app.RTSRD, 'Y')
            zlabel(app.RTSRD, 'Z')
            app.RTSGI = uiaxes(app.GridLayout);
            title(app.RTSGI, 'Rotated translated scaled generated intersections')
            xlabel(app.RTSGI, 'X')
            ylabel(app.RTSGI, 'Y')
            zlabel(app.RTSGI, 'Z')
            app.Intersections = uiaxes(app.GridLayout);
            title(app.Intersections, 'Intersections')
            xlabel(app.Intersections, 'X')
            ylabel(app.Intersections, 'Y')
            zlabel(app.Intersections, 'Z')
            app.Polyroot = uiaxes(app.GridLayout);
            title(app.Polyroot, 'Polyroot')
            xlabel(app.Polyroot, 'X')
            ylabel(app.Polyroot, 'Y')
            zlabel(app.Polyroot, 'Z')
            app.RealMeasures = uiaxes(app.GridLayout);
            title(app.RealMeasures, 'Real Measures')
            xlabel(app.RealMeasures, 'X')
            ylabel(app.RealMeasures, 'Y')
            zlabel(app.RealMeasures, 'Z')
            app.p2Ref = uiaxes(app.GridLayout);
            title(app.p2Ref, '3D Reference')
            xlabel(app.p2Ref, 'X')
            ylabel(app.p2Ref, 'Y')
            zlabel(app.p2Ref, 'Z')
            app.Target = uiaxes(app.GridLayout);
            title(app.Target, 'Target Profile')
            xlabel(app.Target, 'X')
            ylabel(app.Target, 'Y')
            zlabel(app.Target, 'Z')
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ProfileNumberDropDownLabel = uilabel(app.GridLayout3);
            app.ProfileNumberDropDownLabel.Text = 'Profile Number';
            app.ProfileNumberDropDown = uidropdown(app.GridLayout3);
            app.ProfileNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @ProfileNumberDropDownValueChanged, true);
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.AlphaGauge = uigauge(app.GridLayout5, 'ninetydegree');
            app.AlphaGauge.Limits = [0 90];
            app.RadialpositionGauge = uigauge(app.GridLayout5, 'linear');
            app.RadialpositionGauge.Limits = [0 1];
            app.cRg = uilabel(app.GridLayout5);
            app.cRg.Text = '';
            app.cRLabel = uilabel(app.GridLayout5);
            app.cRLabel.Text = 'c/R';
            app.x25g = uilabel(app.GridLayout5);
            app.x25g.Text = '';
            app.X25Label = uilabel(app.GridLayout5);
            app.X25Label.Text = 'X25';
            app.y25g = uilabel(app.GridLayout5);
            app.y25g.Text = '';
            app.Y25Label = uilabel(app.GridLayout5);
            app.Y25Label.Text = 'Y25';
            app.RadialpositionLabel = uilabel(app.GridLayout5);
            app.RadialpositionLabel.Text = 'Radial position';
            app.AlphaLabel = uilabel(app.GridLayout5);
            app.AlphaLabel.Text = 'Alpha°';
            app.Int1 = uilabel(app.GridLayout);
            app.Int1.Text = '';
            app.Int2 = uilabel(app.GridLayout);
            app.Int2.Text = '';
            app.RTSRDlbl = uilabel(app.GridLayout);
            app.RTSRDlbl.Text = '';
            app.DistributionsTab = uitab(app.TabGroup);
            app.DistributionsTab.Title = 'Distributions';
            app.GridLayout6 = uigridlayout(app.DistributionsTab);
            app.Twistax = uiaxes(app.GridLayout6);
            title(app.Twistax, 'Twist')
            xlabel(app.Twistax, 'X')
            ylabel(app.Twistax, 'Y')
            zlabel(app.Twistax, 'Z')
            app.ChordAx = uiaxes(app.GridLayout6);
            title(app.ChordAx, 'Chord')
            xlabel(app.ChordAx, 'X')
            ylabel(app.ChordAx, 'Y')
            zlabel(app.ChordAx, 'Z')
            app.x25ax = uiaxes(app.GridLayout6);
            title(app.x25ax, 'x25')
            xlabel(app.x25ax, 'X')
            ylabel(app.x25ax, 'Y')
            zlabel(app.x25ax, 'Z')
            app.y25ax = uiaxes(app.GridLayout6);
            title(app.y25ax, 'y25')
            xlabel(app.y25ax, 'X')
            ylabel(app.y25ax, 'Y')
            zlabel(app.y25ax, 'Z')
            app.UploadChorddataButton = uibutton(app.GridLayout6, 'push');
            app.UploadChorddataButton.Text = 'Upload Chord data';
            app.UploadTwistDataButton = uibutton(app.GridLayout6, 'push');
            app.UploadTwistDataButton.Text = 'Upload Twist Data';
            app.ClearChorddataButton = uibutton(app.GridLayout6, 'push');
            app.ClearChorddataButton.Text = 'Clear Chord data';
            app.ClearTwistDataButton = uibutton(app.GridLayout6, 'push');
            app.ClearTwistDataButton.Text = 'Clear Twist Data';
            app.InterpolatedPolarTab = uitab(app.TabGroup);
            app.InterpolatedPolarTab.Title = 'Interpolated Polar';
            app.GridLayout7 = uigridlayout(app.InterpolatedPolarTab);
            app.PolarCL = uiaxes(app.GridLayout7);
            title(app.PolarCL, 'CL')
            xlabel(app.PolarCL, 'Alpha °')
            ylabel(app.PolarCL, 'CL')
            zlabel(app.PolarCL, 'Z')
            app.PolarCD = uiaxes(app.GridLayout7);
            title(app.PolarCD, 'CD')
            xlabel(app.PolarCD, 'Alpha °')
            ylabel(app.PolarCD, 'CD')
            zlabel(app.PolarCD, 'Z')
            app.PlotButton = uibutton(app.GridLayout7, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ClearButton = uibutton(app.GridLayout7, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.RadiusEditFieldLabel = uilabel(app.GridLayout7);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.GridLayout7, 'numeric');
            app.RadiusEditField.Value = 0.1;
            app.MachEditFieldLabel = uilabel(app.GridLayout7);
            app.MachEditFieldLabel.Text = 'Mach';
            app.MachEditField = uieditfield(app.GridLayout7, 'numeric');
            app.MachEditField.Value = 0.1;
            app.ReynoldsEditFieldLabel = uilabel(app.GridLayout7);
            app.ReynoldsEditFieldLabel.Text = 'Reynolds';
            app.ReynoldsEditField = uieditfield(app.GridLayout7, 'numeric');
            app.ReynoldsEditField.Value = 23000;
            app.HoldSwitchLabel = uilabel(app.GridLayout7);
            app.HoldSwitchLabel.Text = 'Hold';
            app.HoldSwitch = uiswitch(app.GridLayout7, 'slider');
            app.OOBLabel = uilabel(app.GridLayout7);
            app.OOBLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DatabaseAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end