classdef DatabaseBuilding < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        GeometryExtractionTab          matlab.ui.container.Tab
        Panel                          matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        DeltaField_2                   matlab.ui.control.EditField
        SamplingDeltaLabel_2           matlab.ui.control.Label
        StepsField_2                   matlab.ui.control.EditField
        CutoffpercentageLabel          matlab.ui.control.Label
        DeltaField                     matlab.ui.control.EditField
        SelectGeometryLabel            matlab.ui.control.Label
        GeometryselectDropDown         matlab.ui.control.DropDown
        StepsField                     matlab.ui.control.EditField
        SamplingDeltaLabel             matlab.ui.control.Label
        ExtractionStepsLabel           matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        ParametersandsettingsTab       matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        DatabasesettingsPanel_2        matlab.ui.container.Panel
        GridLayout4_5                  matlab.ui.container.GridLayout
        DatabasesettingsPanel          matlab.ui.container.Panel
        GridLayout4_4                  matlab.ui.container.GridLayout
        ParametersandconstantsPanel    matlab.ui.container.Panel
        GridLayout4_3                  matlab.ui.container.GridLayout
        SavingoptionsPanel             matlab.ui.container.Panel
        GridLayout4_2                  matlab.ui.container.GridLayout
        DatafoldernameEditField        matlab.ui.control.EditField
        DatafoldernameEditFieldLabel   matlab.ui.control.Label
        ToggleModulesPanel             matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        DatabaseGeneratorSwitch        matlab.ui.control.Switch
        DatabaseGeneratorSwitchLabel   matlab.ui.control.Label
        ReplacesbrokenvaluesindatabasewithinterpolatedonesLabel  matlab.ui.control.Label
        UtilizesextractedprofilestocreateaDatabaseLabel  matlab.ui.control.Label
        Enables2DprofilesextracionfromaSTLfileLabel  matlab.ui.control.Label
        GeometryExtractionSwitch       matlab.ui.control.Switch
        GeometryExtractionSwitchLabel  matlab.ui.control.Label
        DatabaseFillerSwitch           matlab.ui.control.Switch
        DatabaseFillerSwitchLabel      matlab.ui.control.Label
        BuildingResultsTab             matlab.ui.container.Tab
        TextArea                       matlab.ui.control.TextArea
    end

    
    methods (Access = public)
        
    end
    
    methods (Access = private)


        function Result=OnToTrue(~,Value)
            if Value=="On"
                Result=true;
            else
                Result=false;
            end
        end
        
        %Update graph for live preview
        function Update_Graphic(app)
            
            %Data retrieving
            StlName = app.GeometryselectDropDown.Value;
            StlPath= "STL\"+StlName;
            [TR] = stlread(StlPath);
            Output=StlGeometryAutomaticFix(TR);
            XX=Output.Points;
            xx=XX(:,1);
            yy=XX(:,2);
            zz=XX(:,3);

            %Plot update
            ax=app.UIAxes;
            axis(ax,"equal")
            plot3(ax,-xx,zz,yy,"."); %Perfect rotation: -xx.zz.yy
            hold(ax,"on")
            plot3(ax,0,0,0,"o","Color","red","MarkerSize",10)
            hold(ax,"off")

            %Cutting planes plot
            xpmin=min(xx);
            xpmax=max(xx);
            ypmin=min(yy);
            ypmax=max(yy);
            [xp,yp] = meshgrid([xpmin xpmax],[ypmin ypmax]);

            Numplanes=str2double(app.StepsField.Value);
            Delta=str2double(app.DeltaField.Value);

            %Plotting cutting planes
            zmin=min(zz);
            zmax=max(zz);
            zmid=(zmax+zmin)/2;
            zetas=linspace(zmin,zmid,Numplanes);
            for z=zetas
                zp = 0.*xp + 0.*xp + z;
                hold(ax,"on")
                surf(ax,-xp,zp,yp,"FaceColor","yellow");
                hold(ax,"off")
            end

            %Plotting delta planes
            rad=zmax-zmid;
            distance=rad*(Delta/100);
            z=distance/2;
            zp1 = 0.*xp + 0.*xp + z;
            zp2 = 0.*xp + 0.*xp - z;
            hold(ax,"on")
            surf(ax,-xp,zp1,yp,"FaceColor","red");
            surf(ax,-xp,zp2,yp,"FaceColor","red");
            hold(ax,"off")
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %Adding crucial directories to path
            addpath("Functions\","STL\")

            %Gettig avaliable stl geometries
            StlList=dir("STL\*.STL");
            StlList=struct2cell(StlList);
            app.GeometryselectDropDown.Items=StlList(1,:);

            %Printing smt on graphic so it isnt empty
            Update_Graphic(app)

        end

        % Clicked callback: GeometryselectDropDown
        function GeometryselectDropDownClicked(app, event)
            Update_Graphic(app)
        end

        % Value changed function: StepsField
        function StepsFieldValueChanged(app, event)
            Update_Graphic(app)
        end

        % Value changed function: DeltaField
        function DeltaFieldValueChanged(app, event)
            Update_Graphic(app)
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            AppParameters.FileName=app.DatafoldernameEditField.Value;
            AppParameters.ExtractorBool=OnToTrue(app,app.GeometryExtractionSwitch.Value);
            AppParameters.XfoilDbBool=OnToTrue(app,app.DatabaseGeneratorSwitch.Value);
            AppParameters.DbFillerBool=OnToTrue(app,app.DatabaseFillerSwitch.Value);
            AppParameters.StlPath=app.GeometryselectDropDown.Value;
            AppParameters.Steps=str2double(app.StepsField.Value);
            AppParameters.Delta=str2double(app.DeltaField.Value);

            Autom8(AppParameters)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeometryExtractionTab = uitab(app.TabGroup);
            app.GeometryExtractionTab.Title = 'Geometry Extraction';
            app.UIAxes = uiaxes(app.GeometryExtractionTab);
            title(app.UIAxes, 'Geometry preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RunButton = uibutton(app.GeometryExtractionTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Panel = uipanel(app.GeometryExtractionTab);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.ExtractionStepsLabel = uilabel(app.GridLayout2);
            app.ExtractionStepsLabel.Text = 'Extraction Steps';
            app.SamplingDeltaLabel = uilabel(app.GridLayout2);
            app.SamplingDeltaLabel.Text = 'Sampling Delta %';
            app.StepsField = uieditfield(app.GridLayout2, 'text');
            app.StepsField.ValueChangedFcn = createCallbackFcn(app, @StepsFieldValueChanged, true);
            app.StepsField.Value = '10';
            app.GeometryselectDropDown = uidropdown(app.GridLayout2);
            app.GeometryselectDropDown.ClickedFcn = createCallbackFcn(app, @GeometryselectDropDownClicked, true);
            app.SelectGeometryLabel = uilabel(app.GridLayout2);
            app.SelectGeometryLabel.Text = 'Select Geometry';
            app.DeltaField = uieditfield(app.GridLayout2, 'text');
            app.DeltaField.ValueChangedFcn = createCallbackFcn(app, @DeltaFieldValueChanged, true);
            app.DeltaField.Value = '2';
            app.CutoffpercentageLabel = uilabel(app.GridLayout2);
            app.CutoffpercentageLabel.Text = 'Cutoff percentage';
            app.StepsField_2 = uieditfield(app.GridLayout2, 'text');
            app.StepsField_2.Value = '10';
            app.SamplingDeltaLabel_2 = uilabel(app.GridLayout2);
            app.SamplingDeltaLabel_2.Text = 'Sampling Delta %';
            app.DeltaField_2 = uieditfield(app.GridLayout2, 'text');
            app.DeltaField_2.Value = '2';
            app.ParametersandsettingsTab = uitab(app.TabGroup);
            app.ParametersandsettingsTab.Title = 'Parameters and settings';
            app.GridLayout3 = uigridlayout(app.ParametersandsettingsTab);
            app.ToggleModulesPanel = uipanel(app.GridLayout3);
            app.ToggleModulesPanel.Title = 'Toggle Modules - For debuging purposes. Leave all to On unless you know what you are doing.';
            app.GridLayout4 = uigridlayout(app.ToggleModulesPanel);
            app.DatabaseFillerSwitchLabel = uilabel(app.GridLayout4);
            app.DatabaseFillerSwitchLabel.Text = 'Database Filler';
            app.DatabaseFillerSwitch = uiswitch(app.GridLayout4, 'slider');
            app.DatabaseFillerSwitch.Value = 'On';
            app.GeometryExtractionSwitchLabel = uilabel(app.GridLayout4);
            app.GeometryExtractionSwitchLabel.Text = 'Geometry Extraction';
            app.GeometryExtractionSwitch = uiswitch(app.GridLayout4, 'slider');
            app.GeometryExtractionSwitch.Value = 'On';
            app.Enables2DprofilesextracionfromaSTLfileLabel = uilabel(app.GridLayout4);
            app.Enables2DprofilesextracionfromaSTLfileLabel.Text = 'Enables 2D profiles extracion from a STL file';
            app.UtilizesextractedprofilestocreateaDatabaseLabel = uilabel(app.GridLayout4);
            app.UtilizesextractedprofilestocreateaDatabaseLabel.Text = 'Utilizes extracted profiles to create a Database';
            app.ReplacesbrokenvaluesindatabasewithinterpolatedonesLabel = uilabel(app.GridLayout4);
            app.ReplacesbrokenvaluesindatabasewithinterpolatedonesLabel.Text = 'Replaces broken values in database with interpolated ones.';
            app.DatabaseGeneratorSwitchLabel = uilabel(app.GridLayout4);
            app.DatabaseGeneratorSwitchLabel.Text = 'Database Generator';
            app.DatabaseGeneratorSwitch = uiswitch(app.GridLayout4, 'slider');
            app.DatabaseGeneratorSwitch.Value = 'On';
            app.SavingoptionsPanel = uipanel(app.GridLayout3);
            app.SavingoptionsPanel.Title = 'Saving options';
            app.GridLayout4_2 = uigridlayout(app.SavingoptionsPanel);
            app.DatafoldernameEditFieldLabel = uilabel(app.GridLayout4_2);
            app.DatafoldernameEditFieldLabel.Text = 'Data folder name';
            app.DatafoldernameEditField = uieditfield(app.GridLayout4_2, 'text');
            app.DatafoldernameEditField.Value = 'NewDatabase';
            app.ParametersandconstantsPanel = uipanel(app.GridLayout3);
            app.ParametersandconstantsPanel.Title = 'Parameters and constants';
            app.GridLayout4_3 = uigridlayout(app.ParametersandconstantsPanel);
            app.DatabasesettingsPanel = uipanel(app.GridLayout3);
            app.DatabasesettingsPanel.Title = 'Database settings';
            app.GridLayout4_4 = uigridlayout(app.DatabasesettingsPanel);
            app.DatabasesettingsPanel_2 = uipanel(app.GridLayout3);
            app.DatabasesettingsPanel_2.Title = 'Database settings';
            app.GridLayout4_5 = uigridlayout(app.DatabasesettingsPanel_2);
            app.BuildingResultsTab = uitab(app.TabGroup);
            app.BuildingResultsTab.Title = 'Building Results';
            app.TextArea = uitextarea(app.BuildingResultsTab);
            app.TextArea.Value = {'Start the extraction to view logs.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DatabaseBuilding

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end