classdef Autom8App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ExtractionSettingsTab           matlab.ui.container.Tab
        GeometryselectionDropDown       matlab.ui.control.DropDown
        GeometryselectionDropDownLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        XfoilSettingsTab                matlab.ui.container.Tab
        ConstantsandParametersTab       matlab.ui.container.Tab
    end

    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        
        
        function Update_Graphic(app)
            %Displaying stl when selected
            StlName = app.GeometryselectionDropDown.Value;
            StlPath= "STL\"+StlName;
            [TR] = stlread(StlPath);
            Output=StlGeometryAutomaticFix(TR);
            XX=Output.Points;

            ax=app.UIAxes;
            axis(ax,"equal")
            plot3(ax,XX(:,1),XX(:,2),XX(:,3),".");
            hold(ax,"on")
            plot3(ax,0,0,0,"o","Color","red","MarkerSize",10)
            hold(ax,"off")
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %Gettig avaliable stl geometries
            StlList=dir("STL\*.STL");
            StlList=struct2cell(StlList);
            app.GeometryselectionDropDown.Items=StlList(1,:);

            %Printing smt on graphic so it isnt empty
            Update_Graphic(app)

        end

        % Clicked callback: GeometryselectionDropDown
        function GeometryselectionDropDownClicked(app, event)
            Update_Graphic(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ExtractionSettingsTab = uitab(app.TabGroup);
            app.ExtractionSettingsTab.Title = 'Extraction Settings';
            app.UIAxes = uiaxes(app.ExtractionSettingsTab);
            title(app.UIAxes, 'Preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GeometryselectionDropDownLabel = uilabel(app.ExtractionSettingsTab);
            app.GeometryselectionDropDownLabel.Text = 'Geometry selection';
            app.GeometryselectionDropDown = uidropdown(app.ExtractionSettingsTab);
            app.GeometryselectionDropDown.ClickedFcn = createCallbackFcn(app, @GeometryselectionDropDownClicked, true);
            app.XfoilSettingsTab = uitab(app.TabGroup);
            app.XfoilSettingsTab.Title = 'Xfoil Settings';
            app.ConstantsandParametersTab = uitab(app.TabGroup);
            app.ConstantsandParametersTab.Title = 'Constants and Parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Autom8App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end